classdef controls < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MassoftheLoadEditFieldLabel  matlab.ui.control.Label
        MassoftheLoadEditField       matlab.ui.control.NumericEditField
        MassoftheCartEditFieldLabel  matlab.ui.control.Label
        MassoftheCartEditField       matlab.ui.control.NumericEditField
        LengthEditFieldLabel         matlab.ui.control.Label
        LengthEditField              matlab.ui.control.NumericEditField
        GravityEditFieldLabel        matlab.ui.control.Label
        GravityEditField             matlab.ui.control.NumericEditField
        ForceEditFieldLabel          matlab.ui.control.Label
        ForceEditField               matlab.ui.control.NumericEditField
        KpEditFieldLabel             matlab.ui.control.Label
        KpEditField                  matlab.ui.control.NumericEditField
        KdEditFieldLabel             matlab.ui.control.Label
        KdEditField                  matlab.ui.control.NumericEditField
        KiEditFieldLabel             matlab.ui.control.Label
        KiEditField                  matlab.ui.control.NumericEditField
        PIDControllerLabel           matlab.ui.control.Label
        ConstantsLabel               matlab.ui.control.Label
        RunSimulationButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            M = app.MassoftheLoadEditField.Value
            m = app.MassoftheCartEditField.Value
            g = app.GravityEditField.Value
            l = app.LengthEditField.Value
            F = app.ForceEditField.Value
            
            % constants of equations of motion y'' = ay + b ,     y is y(t), a and b are constants %
            a = -((M+m)*g)/(m*l)
            b = F/(m*l)
            
            % ... convert that to a TF somehow... %
            G = tf('s')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MassoftheLoadEditFieldLabel = uilabel(app.UIFigure);
            app.MassoftheLoadEditFieldLabel.Text = 'Mass of the Load';
            app.MassoftheLoadEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassoftheLoadEditField.Value = 100;
            app.MassoftheCartEditFieldLabel = uilabel(app.UIFigure);
            app.MassoftheCartEditFieldLabel.Text = 'Mass of the Cart';
            app.MassoftheCartEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassoftheCartEditField.Value = 20;
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.Value = 12;
            app.GravityEditFieldLabel = uilabel(app.UIFigure);
            app.GravityEditFieldLabel.Text = 'Gravity';
            app.GravityEditField = uieditfield(app.UIFigure, 'numeric');
            app.GravityEditField.Value = 10;
            app.ForceEditFieldLabel = uilabel(app.UIFigure);
            app.ForceEditFieldLabel.Text = 'Force';
            app.ForceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceEditField.Value = 5;
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PIDControllerLabel = uilabel(app.UIFigure);
            app.PIDControllerLabel.Text = 'PID Controller';
            app.ConstantsLabel = uilabel(app.UIFigure);
            app.ConstantsLabel.Text = 'Constants';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controls

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end